classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Peso                     matlab.ui.control.Label
        Ejer                     matlab.ui.control.Label
        Alco                     matlab.ui.control.Label
        Fum                      matlab.ui.control.Label
        Procar                   matlab.ui.control.Label
        DLabel                   matlab.ui.control.Label
        NUM                      matlab.ui.control.NumericEditField
        EscribeelIDdelparticipanteEditFieldLabel  matlab.ui.control.Label
        Boton                    matlab.ui.control.Button
        Lista56                  matlab.ui.control.DropDown
        QuequieresgraficarLabel  matlab.ui.control.Label
        Grafica1                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Punto 3.a
load Heart.mat
Edad=heart_data.age;
Edad=Edad/365;
%% Punto 3.B
AF=heart_data.active;%Se extrae unicamente la parte de la tabla que indica actividad fis
T=size(Edad,1);%En T meto la varible de el numero de filas
Af=strings(T,1);%Creo una matriz de 70000 sin datos para poder escribir si Hace ejercicio o no
Sm=strings(T,1);%Creo una matriz de 70000 sin datos para poder escribir si fuma o no
for i= 1:T %Un for para ir de la primera fila a la 70000 y asi poder preguntar si cada persona hace ejercico o no
    if heart_data.active(i) ==0 
        Af(i)="No hace ejercicio"; 
    else 
        Af(i)="Hace ejercicio";
    end
end
heart_data.Haceejercicio=Af; %Se crea una nueva columna y se coloca si hace o no
for i= 1:T
    if heart_data.smoke(i) ==0
        Sm(i)="No fuma";
    else 
        Sm(i)="Si fuma";
    end
end
heart_data.Fuma=Sm; %Se repite pero en este caso si fuma o no
%% Punto 3.c
Altura=heart_data.height;
Peso=heart_data.weight;
Altura=Altura/100;
Altura=Altura.*Altura;
imc=Peso./Altura;
Imc=strings(T,1);
for i= 1:T
    if imc(i)<18.5
        Imc(i)="Esta bajo de peso";
    elseif 18.5<=imc(i) && imc(i)<=25
        Imc(i)="Tiene un peso adecuado";
    else 
        Imc(i)="Tiene sobrepeso";
    end
end
heart_data.IMC=Imc;
%% Punto 3.d
Sis=heart_data.ap_hi;
Dias=heart_data.ap_lo;
Ap=Sis-Dias/3;
Ap=Ap+Dias;
heart_data.presionarterialmedia=Ap;
        end

        % Value changed function: Lista56
        function Lista56ValueChanged(app, event)

        end

        % Button pushed function: Boton
        function BotonButtonPushed(app, event)
            
            load Heart.mat
            PC=heart_data.cardio;
            Altura=heart_data.height;
            Peso=heart_data.weight;
            Altura=Altura/100;
            Altura=Altura.*Altura;
            imc=Peso./Altura;
            value = app.NUM.Value;
            %% todo esto es lo necesario para que se ejecute el codigo (globalizarlo)
            app.DLabel.Text= " El participante numero "+num2str(value) +" Tiene las siguientes caracteristicas:" ;

            if PC(value)==0
                app.Procar.Text= ("-No tiene problemas cardiacos");
            else
                app.Procar.Text= ("-Tiene problemas cardiacos");
            end

            if heart_data.smoke(value) ==0
                 app.Fum.Text= ("-No fuma");
            else 
                 app.Fum.Text= ("-Si fuma");
            end
            if heart_data.alco(value) ==0
                  app.Alco.Text= ("-No Toma alcohol");
            else 
                  app.Alco.Text= ("-Si Toma alcohol");
            end
            if heart_data.active(value) ==0
                  app.Ejer.Text= ("-No hace ejercicio");
            else 
                  app.Ejer.Text= ("-Si hace ejercicio");
            end
            if imc(value)<=18.5
                  app.Peso.Text=("Esta bajo de peso");
            elseif 18.5<=imc(value) && imc(value)<=25
                  app.Peso.Text=("Tiene un peso adecuado");
            else 
                  app.Peso.Text=("Tiene sobrepeso");
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica1 = uiaxes(app.UIFigure);
            title(app.Grafica1, 'Title')
            xlabel(app.Grafica1, 'X')
            ylabel(app.Grafica1, 'Y')
            zlabel(app.Grafica1, 'Z')
            app.QuequieresgraficarLabel = uilabel(app.UIFigure);
            app.QuequieresgraficarLabel.Text = 'Que quieres graficar?';
            app.Lista56 = uidropdown(app.UIFigure);
            app.Lista56.Items = {'Punto 5', 'Punto 6'};
            app.Lista56.ItemsData = {'1', '2'};
            app.Lista56.ValueChangedFcn = createCallbackFcn(app, @Lista56ValueChanged, true);
            app.Lista56.Value = '1';
            app.Boton = uibutton(app.UIFigure, 'push');
            app.Boton.ButtonPushedFcn = createCallbackFcn(app, @BotonButtonPushed, true);
            app.Boton.Text = 'Mostrar los datos';
            app.EscribeelIDdelparticipanteEditFieldLabel = uilabel(app.UIFigure);
            app.EscribeelIDdelparticipanteEditFieldLabel.Text = 'Escribe el ID del participante';
            app.NUM = uieditfield(app.UIFigure, 'numeric');
            app.DLabel = uilabel(app.UIFigure);
            app.DLabel.Text = ':D';
            app.Procar = uilabel(app.UIFigure);
            app.Procar.Text = '';
            app.Fum = uilabel(app.UIFigure);
            app.Fum.Text = '';
            app.Alco = uilabel(app.UIFigure);
            app.Alco.Text = '';
            app.Ejer = uilabel(app.UIFigure);
            app.Ejer.Text = '';
            app.Peso = uilabel(app.UIFigure);
            app.Peso.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end